classdef colorCGMdisplay < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        generalGridLayout              matlab.ui.container.GridLayout
        TabGroup                       matlab.ui.container.TabGroup
        InTab                          matlab.ui.container.Tab
        InGridLayout                   matlab.ui.container.GridLayout
        LoadButton                     matlab.ui.control.Button
        OPDbuttonImage                 matlab.ui.control.Image
        importFluoEditField            matlab.ui.control.EditField
        importOPDEditField             matlab.ui.control.EditField
        FluoButtonImage                matlab.ui.control.Image
        ImportFluoimageLabel           matlab.ui.control.Label
        ImportOPDimageLabel            matlab.ui.control.Label
        scalesTab                      matlab.ui.container.Tab
        GridLayout                     matlab.ui.container.GridLayout
        maxFluoEditField               matlab.ui.control.NumericEditField
        minLabel_2                     matlab.ui.control.Label
        minFluoEditField               matlab.ui.control.NumericEditField
        minLabel                       matlab.ui.control.Label
        BoundariesoftheFluoimageLabel  matlab.ui.control.Label
        maxOPDEditField                matlab.ui.control.NumericEditField
        maxLabel                       matlab.ui.control.Label
        minOPDEditField                matlab.ui.control.NumericEditField
        minEditFieldLabel              matlab.ui.control.Label
        BoundariesoftheOPDimageLabel   matlab.ui.control.Label
        MixParametersTab               matlab.ui.container.Tab
        ParamGridLayout                matlab.ui.container.GridLayout
        thresholdSlider                matlab.ui.control.Slider
        thresholdSliderLabel           matlab.ui.control.Label
        invertedFluoLUTCheckBox        matlab.ui.control.CheckBox
        invertedOPDLUTCheckBox         matlab.ui.control.CheckBox
        HighPassEditField              matlab.ui.control.NumericEditField
        HighPassEditFieldLabel         matlab.ui.control.Label
        maxFluoSlider                  matlab.ui.control.Slider
        maxFluoSliderLabel             matlab.ui.control.Label
        minFluoSlider                  matlab.ui.control.Slider
        minFluoSliderLabel             matlab.ui.control.Label
        maxOPDSlider                   matlab.ui.control.Slider
        maxOPDSliderLabel              matlab.ui.control.Label
        minOPDSlider                   matlab.ui.control.Slider
        minOPDSliderLabel              matlab.ui.control.Label
        OutTab                         matlab.ui.container.Tab
        OutGridLayout                  matlab.ui.container.GridLayout
        ExportButton                   matlab.ui.control.Button
        epsLabel                       matlab.ui.control.Label
        ExportFileNameLabel            matlab.ui.control.Label
        ExportFolderNameLabel          matlab.ui.control.Label
        ExportFileNameEditField        matlab.ui.control.EditField
        ExportFolderNameEditField      matlab.ui.control.EditField
        ExportmixedimageLabel          matlab.ui.control.Label
        exportButtonImage              matlab.ui.control.Image
        axFluo                         matlab.ui.control.UIAxes
        axMix                          matlab.ui.control.UIAxes
        axOPD                          matlab.ui.control.UIAxes
    end

    
    properties
        imOPD % OPD image container
        imFluo % Fluo image container
        imMix
        cbOPD
        cbFluo
        path
    end

    properties(Dependent)
        Fluo % Fluo image
        OPD  % OPD image
        Mix  % Mixed RGB image
    end


    methods  % set and get methods

        % These set and get methods ensure that app.Fluo and app.OPD are
        % always what is seen on the images of the app.

        function val = get.Fluo(app) % get the Fluo image by reading what is the image object contained in the Axes object
            val = app.imFluo.CData;
        end

        function val = get.OPD(app) % get the OPD image by reading what is the image object contained in the Axes object
            val = app.imOPD.CData;
        end

        function val = get.Mix(app) % get the Mix image by reading what is the image object contained in the Axes object
            val = app.imMix.CData;
        end

        function set.Fluo(app,val)
            app.imFluo.CData = val;
            app.axFluo.XLimMode='auto';
            app.axFluo.YLimMode='auto';
            app.axFluo.XLimitMethod='tight';
            app.axFluo.YLimitMethod='tight';
            minVal = min(val(:));
            maxVal = max(val(:));
            if minVal == maxVal
                minVal = 0;
                maxVal = 1;
            end
            ampl = maxVal-minVal;
            app.minFluoSlider.Limits = [ minVal-ampl maxVal ];
            app.minFluoSlider.Value  =   minVal;
            app.maxFluoSlider.Limits = [ minVal maxVal ];
            app.maxFluoSlider.Value  =   maxVal;
            drawnow
            app.updateMixedIm()
        end

        function set.OPD(app,val)
            app.imOPD.CData = val;
            app.axOPD.XLimMode='auto';
            app.axOPD.YLimMode='auto';
            app.axOPD.XLimitMethod='tight';
            app.axOPD.YLimitMethod='tight';
            minVal = min(val(:));
            maxVal = max(val(:));
            if minVal == maxVal
                minVal = 0;
                maxVal = 1;
            end
            ampl = maxVal-minVal;
            app.minOPDSlider.Limits = 1e9 * [ minVal-ampl minVal+ampl/2 ];
            app.minOPDSlider.Value  = 1e9 *   minVal;
            app.maxOPDSlider.Limits = 1e9 * [ maxVal-ampl/2 maxVal+ampl ];
            app.maxOPDSlider.Value  = 1e9 *   maxVal;
            drawnow
            app.updateMixedIm()
        end

        function set.Mix(app,val)
            app.imMix.CData = val;
            app.axMix.XLimMode='auto';
            app.axMix.YLimMode='auto';
            app.axMix.XLimitMethod='tight';
            app.axMix.YLimitMethod='tight';
            drawnow
        end

    end

    methods %(Access = private)

        function updateImDisplay(app,name)
            arguments
                app
                name {mustBeMember(name,{'OPD','Fluo','both'})} % which of the two types of images
            end

            switch name
                case 'both'
                    updateImDisplay(app,'OPD')
                    updateImDisplay(app,'Fluo')
                case 'OPD'
                    fileName = app.importOPDEditField.Value;
                    if ~isempty(fileName)
                        app.OPD = readmatrix(fileName);
                    else
                        app.OPD = app.Fluo*0;
                    end
                    minVal = min(app.OPD(:));
                    maxVal = max(app.OPD(:));
                    if minVal < maxVal
                        app.minOPDEditField.Value = minVal;
                        app.maxOPDEditField.Value = maxVal;
                    else
                        app.minOPDEditField.Value = -1;
                        app.maxOPDEditField.Value = 1;
                    end
                    app.adjustScales('OPD')

                    drawnow

                    % xlim(app.axOPD,[1 size(app.OPD,2)]); 
                    % ylim(app.axOPD,[1 size(app.OPD,1)]); 
                    % app.axOPD.XLim=[1 size(app.OPD,2)]; 
                    % app.axOPD.YLim=[1 size(app.OPD,1)]; 

                case 'Fluo'
                    fileName = app.importFluoEditField.Value;
                    if ~isempty(fileName)
                        app.Fluo = readmatrix(fileName);
                    else
                        app.Fluo = app.OPD*0;
                    end
                    minVal = min(app.Fluo(:));
                    maxVal = max(app.Fluo(:));
                    if minVal < maxVal
                        app.minFluoEditField.Value = minVal;
                        app.maxFluoEditField.Value = maxVal;
                    else
                        app.minFluoEditField.Value = -1;
                        app.maxFluoEditField.Value = 1;
                    end

                    drawnow
           
                    % xlim(app.axFluo,[1 size(app.Fluo,2)]); 
                    % ylim(app.axFluo,[1 size(app.Fluo,1)]); 

            end

        end
        
        function updateMixedIm(app)
            if ~sum(size(app.OPD)~=size(app.Fluo)) % if the two images have the same size
                minOPD = app.minOPDSlider.Value;
                maxOPD = app.maxOPDSlider.Value;
                minFluo = app.minFluoSlider.Value;
                maxFluo = app.maxFluoSlider.Value;
                HD = app.HighPassEditField.Value;
                
                app.Mix = imagedual(app.OPD, app.Fluo, 'minOPD', minOPD,'maxOPD',maxOPD,'minFluo', ...
                    minFluo, 'maxFluo', maxFluo, 'sigmaHP', HD, ...
                    "invLUT_OPD", app.invertedOPDLUTCheckBox.Value, ...
                    "invLUT_Fluo", app.invertedFluoLUTCheckBox.Value, ...
                    "thresh", app.thresholdSlider.Value);
            end
        end


    end
    
    methods (Access = private)
        
        function adjustScales(app,which)
            switch which
                case 'OPD'
                    min = app.minOPDEditField.Value;
                    max = app.maxOPDEditField.Value;
                    clim(app.axOPD,[min, max])
                case 'Fluo'
                    min = app.minFluoEditField.Value;
                    max = app.maxFluoEditField.Value;
                    clim(app.axFluo,[min, max])
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath(pwd))
            addpath(genpath('../CGMprocess'))
            app.path = pwd;

            imagesc(app.axFluo,zeros(100));
            imagesc(app.axOPD,zeros(100));
            imagesc(app.axMix,zeros(100));

            app.imFluo = app.axFluo.Children;
            app.cbFluo = colorbar(app.axFluo);

            app.imOPD = app.axOPD.Children;
            app.cbOPD = colorbar(app.axOPD);

            app.imMix = app.axMix.Children;

            set(app.axFluo, 'DataAspectRatio', [1 1 1])
            set(app.axOPD,  'DataAspectRatio', [1 1 1])
            set(app.axMix,  'DataAspectRatio', [1 1 1])

            colormap(app.axFluo,invertedGreen)
            colormap(app.axOPD,phase1024)

            app.updateImDisplay('both')

            %xlim(app.axMix,[1 size(app.Mix,2)]); 
            %ylim(app.axMix,[1 size(app.Mix,1)]); 

        end

        % Image clicked function: FluoButtonImage, OPDbuttonImage, 
        % ...and 1 other component
        function openFolder(app, event)
            app.UIFigure.Visible='off' ;          
 
            switch event.Source.Tag
                case 'OPDbutton'
                    [file, app.path] = uigetfile([app.path '/*.txt']);
                    app.importOPDEditField.Value  = [app.path file];
                case 'FluoButton'
                    [file, app.path] = uigetfile([app.path '/*.txt']);
                    app.importFluoEditField.Value = [app.path file];
                case 'exportFolderButton'
                    app.path = uigetdir(app.path);
                    if app.path ~= 0
                        app.ExportFolderNameEditField.Value = app.path;
                    end
            end
            app.UIFigure.Visible='on';

        end

        % Button pushed function: LoadButton
        function loadImages(app, event)
            app.updateImDisplay('both')
            app.updateMixedIm()
        end

        % Value changed function: HighPassEditField, 
        % ...and 7 other components
        function updateMixCallback(app, event)
            app.updateMixedIm()
            % TODO : updater le callback de HP pour changer les bornes des slider quand on change la valeur de HP
        end

        % Button pushed function: ExportButton
        function exportImages(app, event)
            ax1 = copy(app.axFluo);
            ax2 = copy(app.axOPD);
            ax3 = copy(app.axMix);

            app.UIFigure.Visible='off' ;          

            hfig = figure;
            hfig.Position(3:4) = [920,410];
            hfig.Color = [1 1 1];
            hfig.ToolBar = "none";

            ax1.Parent = hfig;
            ax1.Position = [10,10,300,300];
            cb1 = colorbar();
            cb1.Label.String = 'nm'; 
            
            ax2.Parent = hfig;
            ax2.Position = [310,10,300,300];
            cb2=colorbar(ax2);
            cb2.Label.String = 'nm'; 
            
            ax3.Parent = hfig;
            ax3.Position = [610,10,300,300];
            cb3=colorbar();
            cb3.Label.String = 'nm'; 
            
            saveas(hfig,[app.ExportFolderNameEditField.Value '/' app.ExportFileNameEditField.Value],'epsc')
            app.UIFigure.Visible='on' ;          
           

        end

        % Value changed function: maxFluoEditField, maxOPDEditField, 
        % ...and 2 other components
        function adjustScalesCallback(app, event)

            app.adjustScales(event.Source.Tag)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.generalGridLayout = uigridlayout(app.UIFigure);
            app.axOPD = uiaxes(app.generalGridLayout);
            title(app.axOPD, 'OPD')
            app.axMix = uiaxes(app.generalGridLayout);
            title(app.axMix, 'Mixed image')
            xlabel(app.axMix, 'X')
            ylabel(app.axMix, 'Y')
            zlabel(app.axMix, 'Z')
            app.axFluo = uiaxes(app.generalGridLayout);
            title(app.axFluo, 'Fluo')
            app.TabGroup = uitabgroup(app.generalGridLayout);
            app.InTab = uitab(app.TabGroup);
            app.InTab.Title = 'In';
            app.InGridLayout = uigridlayout(app.InTab);
            app.ImportOPDimageLabel = uilabel(app.InGridLayout);
            app.ImportOPDimageLabel.Text = 'Import OPD image';
            app.ImportFluoimageLabel = uilabel(app.InGridLayout);
            app.ImportFluoimageLabel.Text = 'Import Fluo image';
            app.FluoButtonImage = uiimage(app.InGridLayout);
            app.FluoButtonImage.ImageClickedFcn = createCallbackFcn(app, @openFolder, true);
            app.FluoButtonImage.Tag = 'FluoButton';
            app.FluoButtonImage.ImageSource = fullfile(pathToMLAPP, 'gui', 'folder2.svg');
            app.importOPDEditField = uieditfield(app.InGridLayout, 'text');
            app.importOPDEditField.Editable = 'off';
            app.importFluoEditField = uieditfield(app.InGridLayout, 'text');
            app.importFluoEditField.Editable = 'off';
            app.OPDbuttonImage = uiimage(app.InGridLayout);
            app.OPDbuttonImage.ImageClickedFcn = createCallbackFcn(app, @openFolder, true);
            app.OPDbuttonImage.Tag = 'OPDbutton';
            app.OPDbuttonImage.ImageSource = fullfile(pathToMLAPP, 'gui', 'folder2.svg');
            app.LoadButton = uibutton(app.InGridLayout, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @loadImages, true);
            app.LoadButton.Text = 'Load';
            app.scalesTab = uitab(app.TabGroup);
            app.scalesTab.Title = 'scales';
            app.GridLayout = uigridlayout(app.scalesTab);
            app.BoundariesoftheOPDimageLabel = uilabel(app.GridLayout);
            app.BoundariesoftheOPDimageLabel.Text = 'Boundaries of the OPD image';
            app.minEditFieldLabel = uilabel(app.GridLayout);
            app.minEditFieldLabel.Text = 'min';
            app.minOPDEditField = uieditfield(app.GridLayout, 'numeric');
            app.minOPDEditField.ValueChangedFcn = createCallbackFcn(app, @adjustScalesCallback, true);
            app.minOPDEditField.Tag = 'OPD';
            app.maxLabel = uilabel(app.GridLayout);
            app.maxLabel.Text = 'max';
            app.maxOPDEditField = uieditfield(app.GridLayout, 'numeric');
            app.maxOPDEditField.ValueChangedFcn = createCallbackFcn(app, @adjustScalesCallback, true);
            app.maxOPDEditField.Tag = 'OPD';
            app.BoundariesoftheFluoimageLabel = uilabel(app.GridLayout);
            app.BoundariesoftheFluoimageLabel.Text = 'Boundaries of the Fluo image';
            app.minLabel = uilabel(app.GridLayout);
            app.minLabel.Text = 'min';
            app.minFluoEditField = uieditfield(app.GridLayout, 'numeric');
            app.minFluoEditField.ValueChangedFcn = createCallbackFcn(app, @adjustScalesCallback, true);
            app.minFluoEditField.Tag = 'Fluo';
            app.minLabel_2 = uilabel(app.GridLayout);
            app.minLabel_2.Text = 'max';
            app.maxFluoEditField = uieditfield(app.GridLayout, 'numeric');
            app.maxFluoEditField.ValueChangedFcn = createCallbackFcn(app, @adjustScalesCallback, true);
            app.maxFluoEditField.Tag = 'Fluo';
            app.MixParametersTab = uitab(app.TabGroup);
            app.MixParametersTab.Title = 'Mix Parameters';
            app.ParamGridLayout = uigridlayout(app.MixParametersTab);
            app.minOPDSliderLabel = uilabel(app.ParamGridLayout);
            app.minOPDSliderLabel.Text = 'min OPD';
            app.minOPDSlider = uislider(app.ParamGridLayout);
            app.minOPDSlider.ValueChangedFcn = createCallbackFcn(app, @updateMixCallback, true);
            app.maxOPDSliderLabel = uilabel(app.ParamGridLayout);
            app.maxOPDSliderLabel.Text = 'max OPD';
            app.maxOPDSlider = uislider(app.ParamGridLayout);
            app.maxOPDSlider.ValueChangedFcn = createCallbackFcn(app, @updateMixCallback, true);
            app.maxOPDSlider.Value = 85.4031032986111;
            app.minFluoSliderLabel = uilabel(app.ParamGridLayout);
            app.minFluoSliderLabel.Text = 'min Fluo';
            app.minFluoSlider = uislider(app.ParamGridLayout);
            app.minFluoSlider.ValueChangedFcn = createCallbackFcn(app, @updateMixCallback, true);
            app.minFluoSlider.Value = 88.7767650462963;
            app.maxFluoSliderLabel = uilabel(app.ParamGridLayout);
            app.maxFluoSliderLabel.Text = 'max Fluo';
            app.maxFluoSlider = uislider(app.ParamGridLayout);
            app.maxFluoSlider.ValueChangedFcn = createCallbackFcn(app, @updateMixCallback, true);
            app.maxFluoSlider.Value = 30;
            app.HighPassEditFieldLabel = uilabel(app.ParamGridLayout);
            app.HighPassEditFieldLabel.Text = 'High Pass';
            app.HighPassEditField = uieditfield(app.ParamGridLayout, 'numeric');
            app.HighPassEditField.ValueChangedFcn = createCallbackFcn(app, @updateMixCallback, true);
            app.HighPassEditField.Value = 40;
            app.invertedOPDLUTCheckBox = uicheckbox(app.ParamGridLayout);
            app.invertedOPDLUTCheckBox.ValueChangedFcn = createCallbackFcn(app, @updateMixCallback, true);
            app.invertedOPDLUTCheckBox.Text = 'inverted LUT';
            app.invertedFluoLUTCheckBox = uicheckbox(app.ParamGridLayout);
            app.invertedFluoLUTCheckBox.ValueChangedFcn = createCallbackFcn(app, @updateMixCallback, true);
            app.invertedFluoLUTCheckBox.Text = 'inverted LUT';
            app.thresholdSliderLabel = uilabel(app.ParamGridLayout);
            app.thresholdSliderLabel.Text = 'threshold';
            app.thresholdSlider = uislider(app.ParamGridLayout);
            app.thresholdSlider.Limits = [0 255];
            app.thresholdSlider.MajorTicks = [0 128 255];
            app.thresholdSlider.ValueChangedFcn = createCallbackFcn(app, @updateMixCallback, true);
            app.OutTab = uitab(app.TabGroup);
            app.OutTab.Title = 'Out';
            app.OutGridLayout = uigridlayout(app.OutTab);
            app.exportButtonImage = uiimage(app.OutGridLayout);
            app.exportButtonImage.ImageClickedFcn = createCallbackFcn(app, @openFolder, true);
            app.exportButtonImage.Tag = 'exportFolderButton';
            app.exportButtonImage.ImageSource = fullfile(pathToMLAPP, 'gui', 'folder2.svg');
            app.ExportmixedimageLabel = uilabel(app.OutGridLayout);
            app.ExportmixedimageLabel.Text = 'Export mixed image';
            app.ExportFolderNameEditField = uieditfield(app.OutGridLayout, 'text');
            app.ExportFileNameEditField = uieditfield(app.OutGridLayout, 'text');
            app.ExportFolderNameLabel = uilabel(app.OutGridLayout);
            app.ExportFolderNameLabel.Text = 'Folder';
            app.ExportFileNameLabel = uilabel(app.OutGridLayout);
            app.ExportFileNameLabel.Text = 'Name';
            app.epsLabel = uilabel(app.OutGridLayout);
            app.epsLabel.Text = '.eps';
            app.ExportButton = uibutton(app.OutGridLayout, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @exportImages, true);
            app.ExportButton.Text = 'Export';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = colorCGMdisplay

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end